classdef timer_4_2_realtime_line1prolem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Tab2                     matlab.ui.container.Tab
        SensorPanel              matlab.ui.container.Panel
        UIAxes3                  matlab.ui.control.UIAxes
        LaserStatusLabel         matlab.ui.control.Label
        HeatmapCountLabel        matlab.ui.control.Label
        UsThresholdSlider        matlab.ui.control.Slider
        UsThresholdSliderLabel   matlab.ui.control.Label
        UsThresholdLabel         matlab.ui.control.Label
        YPositionEditField       matlab.ui.control.NumericEditField
        YPositionEditFieldLabel  matlab.ui.control.Label
        XPositionEditField       matlab.ui.control.NumericEditField
        XPositionEditFieldLabel  matlab.ui.control.Label
        stopButton               matlab.ui.control.Button
        startButton              matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Interface     
        Timer          timer
        Data           table = table()
        Running        logical = false
        SampleIndex    double = 0
        LaserDataFile
        MemoryDataFile
    end
    
    methods (Access = private)

        function startupFcn(app)
            app.data = table([], [], [], 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
            app.LaserDataFile = fullfile('D:\GONG\data', 'laser_data.txt');
            app.MemoryDataFile = fullfile('D:\GONG\data', 'memory_data.txt');
        end


        function loopAcquisition(app)
            app.Running = true;

            while app.Running
                try
                    % 1. 清除旧连接（如果有）
                    if ~isempty(app.Interface) && isvalid(app.Interface)
                        clear app.Interface;
                        app.Interface = [];
                    end

                    % 2. 连接 ESP32
                    disp("🔄 正在连接 ESP32...");
                    app.Interface = tcpclient("10.203.196.138", 5025, "Timeout", 5);
                    disp("✅ 已连接 ESP32");

                    % 3. 开始采集（一次批量）
                    writeline(app.Interface, "INITiate:CONTinuous:ALL ON");
                    pause(0.2);  % 等设备准备好
                    app.readAndPlotData();

                    % 4. 主动关闭连接（模拟 ESP32 自动断开）
                    clear app.Interface;
                    app.Interface = [];

                    % 5. 等待下次尝试
                    pause(2);  % 等待 2 秒继续连接下一次

                catch ME
                    disp("⚠️ 连接失败或中断：" + ME.message);
                    pause(3);  % 失败后多等一会再重试
                end
            end
        end

        function stopAcquisition(app)
            app.Running = false;
            disp("🛑 采集停止");

            if ~isempty(app.Interface) && isvalid(app.Interface)
                try
                    writeline(app.Interface, "INITiate:CONTinuous OFF");
                catch
                end
                clear app.Interface;
                app.Interface = [];
            end
        end

        function readAndPlotData(app)

            try
                writeline(app.Interface, "DATA:POINTS?");
                pause(0.3);

                count = 0;
                if app.Interface.NumBytesAvailable > 0
                    countStr = readline(app.Interface);
                    count = str2double(strtrim(countStr));
                end

                if count > 0
                    writeline(app.Interface, "DATA:REMOVE? " + num2str(count));
                    pause(0.3);

                    while count > 0 && app.Interface.NumBytesAvailable > 0
                        line1 = strtrim(readline(app.Interface));

                        % === 安全性判断 ===
                        if isempty(line1) || ~contains(line1, ',')
                            disp("❌ line1 无效，跳过：" + line1);
                            count = count - 1;
                            continue;
                        end

                        % === 写入文件（换行）===
                        fid = fopen('D:\GONG\data\laser_data.txt', 'a');
                        fprintf(fid, '%s\n', line1);
                        fclose(fid);

                        parts = split(line1, ',');

                        if numel(parts) >= 12
                            ux = str2double(parts(10));
                            uy = str2double(parts(11));
                            us = str2double(parts(12));

                            threshold = app.UsThresholdSlider.Value;
                            fprintf("us = %.5f, 阈值 = %.3f\n", us, threshold);

                            if us < threshold
                                if isprop(app, 'LaserStatusLabel') && isvalid(app.LaserStatusLabel)
                                    app.LaserStatusLabel.Text = "状态：无激光";
                                end

                                % 插入 NaN 占位，维持时间轴连续
                                app.Data(end+1, :) = table(NaN, NaN, us, ...
                                    'VariableNames', {'uxVoltage', 'uyVoltage', 'usVoltage'});

                                if height(app.Data) > 300
                                    app.Data = tail(app.Data, 300);
                                end

                                % 清空图或保持热力图状态刷新
                                app.updateSpotOffset();

                                count = count - 1;
                                continue;
                            else
                                if isprop(app, 'LaserStatusLabel') && isvalid(app.LaserStatusLabel)
                                    app.LaserStatusLabel.Text = "Status: collecting laser information";
                                end
                            end

                            if all(~isnan([ux, uy, us]))
                                app.SampleIndex = app.SampleIndex + 1;
                                app.Data(end+1, :) = table(ux, uy, us, ...
                                    'VariableNames', {'uxVoltage', 'uyVoltage', 'usVoltage'});

                                if height(app.Data) > 300
                                    app.Data = tail(app.Data, 300);
                                end

                                % === 散点图更新 ===
                                app.updateScatterPlot(ux, uy, us)
                                % cla(app.UIAxes);
                                % hold(app.UIAxes, 'on');
                                % plot(app.UIAxes, [-0.5, 0.5], [0, 0], 'k-', 'LineWidth', 1.5);
                                % plot(app.UIAxes, [0, 0], [-0.5, 0.5], 'k-', 'LineWidth', 1.5);
                                % scatter(app.UIAxes, ux, uy, 60, us, 'filled');
                                % hold(app.UIAxes, 'off');
                                % app.XPositionEditField.Value = ux;
                                % app.YPositionEditField.Value = uy;
                                % xlim(app.UIAxes, [-0.5, 0.5]);
                                % ylim(app.UIAxes, [-0.5, 0.5]);
                                % colormap(app.UIAxes, turbo);
                                % colorbar(app.UIAxes);
                                % grid(app.UIAxes, 'on');
                                % title(app.UIAxes, 'Latest ux vs uy');
                                % xlabel(app.UIAxes, 'uxVoltage');
                                % ylabel(app.UIAxes, 'uyVoltage');

                                % === 热力图更新 ===
                                app.updateSpotOffset();
                                drawnow limitrate;
                            end
                        end
                        count = count - 1;
                    end
                    line2 = writeread(app.Interface, "SYSTem:DEBUg:MEMory?");
                    writelines(line2, 'memory_data.txt', WriteMode="append", LineEnding=" ");
                    disp("write memory data into：" + app.MemoryDataFile);
                end

            catch ME
                disp("❌ readAndPlotData 错误：" + ME.message);
            end
        end

        function updateScatterPlot(app, ux, uy, us)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % Draw cross axes
            plot(app.UIAxes, [-0.5, 0.5], [0, 0], 'k-', 'LineWidth', 1.5);
            plot(app.UIAxes, [0, 0], [-0.5, 0.5], 'k-', 'LineWidth', 1.5);

            % Draw scatter point
            scatter(app.UIAxes, ux, uy, 60, us, 'filled');
            hold(app.UIAxes, 'off');

            % Update axes
            xlim(app.UIAxes, [-0.5, 0.5]);
            ylim(app.UIAxes, [-0.5, 0.5]);
            colormap(app.UIAxes, turbo);
            colorbar(app.UIAxes);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Latest ux vs uy');
            xlabel(app.UIAxes, 'uxVoltage');
            ylabel(app.UIAxes, 'uyVoltage');

            % Update displayed values
            app.XPositionEditField.Value = ux;
            app.YPositionEditField.Value = uy;
        end

        function updateSpotOffset(app)
            N = 30;
            if height(app.Data) < 1
                return;
            end

            recentData = tail(app.Data, min(N, height(app.Data)));
            allX = recentData.uxVoltage;
            allY = recentData.uyVoltage;
            allZ = recentData.usVoltage;

            threshold = app.UsThresholdSlider.Value;
            validIdx = allZ >= threshold;
            allX = allX(validIdx);
            allY = allY(validIdx);
            allZ = allZ(validIdx);
           
            if isprop(app, 'HeatmapCountLabel') && isvalid(app.HeatmapCountLabel)
                app.HeatmapCountLabel.Text = "the points of heatmap：" + num2str(numel(allZ));
            end
            
            if isempty(allZ)
                cla(app.UIAxes2);
                title(app.UIAxes2, 'NO LASER BEAM');
                return;
            end

            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');

            x_range = linspace(min(allX), max(allX), 50);
            y_range = linspace(min(allY), max(allY), 50);
            [X, Y] = meshgrid(x_range, y_range);
            Z = griddata(allX, allY, allZ, X, Y, 'cubic');

            x0 = sum(allX .* allZ) / sum(allZ);
            y0 = sum(allY .* allZ) / sum(allZ);

            imagesc(app.UIAxes2, x_range, y_range, Z);
            axis(app.UIAxes2, 'tight');
            axis(app.UIAxes2, 'equal');
            set(app.UIAxes2, 'YDir', 'normal');
            colormap(app.UIAxes2, turbo);
            colorbar(app.UIAxes2);

            plot(app.UIAxes2, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            scatter(app.UIAxes2, allX, allY, 30, allZ, 'filled', 'MarkerEdgeColor', 'k');
           
            text(app.UIAxes2, x0 + 0.01, y0 + 0.01, 'central point', 'Color', 'r', 'FontSize', 10);
            text(app.UIAxes2, x0, y0 - 0.02, sprintf('ΔX = %.3f\nΔY = %.3f', x0, y0), ...
                'Color', 'r', 'FontSize', 10, 'FontWeight', 'bold');
            
            quiver(app.UIAxes2, 0, 0, x0, y0, 0, 'b', 'LineWidth', 2);
            plot(app.UIAxes2, 0, 0, 'wx', 'MarkerSize', 10, 'LineWidth', 2);

            xlabel(app.UIAxes2, 'X position');
            ylabel(app.UIAxes2, 'Y position');
            title(app.UIAxes2, 'Laser Spot Offset');
            app.HeatmapCountLabel.Text = "the points of the heatmap：" + num2str(numel(allZ));
            hold(app.UIAxes2, 'off');
        end            
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.Data = table();
            app.SampleIndex = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Running = true;
            app.loopAcquisition();
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stopAcquisition(app);
        end

        % Value changed function: UsThresholdSlider
        function UsThresholdSliderValueChanged(app, event)
            newValue = app.UsThresholdSlider.Value;
            app.UsThresholdLabel.Text = "recent threshold：" + sprintf('%.3f', newValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.XPositionEditFieldLabel = uilabel(app.UIFigure);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPositionEditFieldLabel = uilabel(app.UIFigure);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsThresholdLabel = uilabel(app.UIFigure);
            app.UsThresholdLabel.Text = 'the einvironment threshold:';
            app.UsThresholdSliderLabel = uilabel(app.UIFigure);
            app.UsThresholdSliderLabel.Text = 'UsThresholdSlider';
            app.UsThresholdSlider = uislider(app.UIFigure);
            app.UsThresholdSlider.Limits = [0 0.2];
            app.UsThresholdSlider.MajorTicks = [0 0.05 0.1 0.15 0.2];
            app.UsThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UsThresholdSliderValueChanged, true);
            app.HeatmapCountLabel = uilabel(app.UIFigure);
            app.HeatmapCountLabel.Text = 'the points of heatmap';
            app.LaserStatusLabel = uilabel(app.UIFigure);
            app.LaserStatusLabel.Text = 'LaserStatusLabel';
            app.SensorPanel = uipanel(app.UIFigure);
            app.SensorPanel.Title = 'SensorPanel';
            app.UIAxes3 = uiaxes(app.SensorPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timer_4_2_realtime_line1prolem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end