classdef timer_4_2_realtimeok < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        RealtimeTab                 matlab.ui.container.Tab
        HeatmapCountLabel           matlab.ui.control.Label
        UsThresholdSlider           matlab.ui.control.Slider
        UsThresholdSliderLabel      matlab.ui.control.Label
        UsThresholdLabel            matlab.ui.control.Label
        LaserStatusLabel            matlab.ui.control.Label
        YPositionEditField          matlab.ui.control.NumericEditField
        YPositionEditFieldLabel     matlab.ui.control.Label
        XPositionEditField          matlab.ui.control.NumericEditField
        XPositionEditFieldLabel     matlab.ui.control.Label
        stopButton                  matlab.ui.control.Button
        startButton                 matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        AnalysisTab                 matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        LoadPlotDataButton          matlab.ui.control.Button
        FFTofuxVoltagePanel         matlab.ui.container.Panel
        FFTAxes                     matlab.ui.control.UIAxes
        ESP32MemoryUsagePanel       matlab.ui.container.Panel
        FreeHeapAxes                matlab.ui.control.UIAxes
        StackSizeAxes               matlab.ui.control.UIAxes
        BusVoltagePanel             matlab.ui.container.Panel
        VBusAxes                    matlab.ui.control.UIAxes
        SensorVoltageChannelsPanel  matlab.ui.container.Panel
        VoltageAxes                 matlab.ui.control.UIAxes
        StatusOverTimePanel         matlab.ui.container.Panel
        StatusAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Interface     
        Timer          timer
        Data           table = table()
        data
        memory_data
        Running        logical = false
        SampleIndex    double = 0
        LaserDataFile
        MemoryDataFile
    end
    
    methods (Access = private)

        function startupFcn(app)
            app.data = table([], [], [], 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
            app.LaserDataFile = fullfile('D:\GONG\data', 'laser_data.txt');
            app.MemoryDataFile = fullfile('D:\GONG\data', 'memory_data.txt');
        end


        function loopAcquisition(app)
            app.Running = true;

            while app.Running
                try
                    % 1. Clear any previous TCP connection
                    if ~isempty(app.Interface) && isvalid(app.Interface)
                        clear app.Interface;
                        app.Interface = [];
                    end

                    % 2. Connect to the ESP32 device
                    disp("Connecting to ESP32...");
                    app.Interface = tcpclient("10.203.196.138", 5025, "Timeout", 5);
                    disp("ESP32 connected");

                    % 3. Start one batch of data acquisition
                    writeline(app.Interface, "INITiate:CONTinuous:ALL ON");
                    pause(0.2); 
                    app.readAndPlotData();

                    % 4.Manually close the connection (simulate ESP32 auto-disconnect)
                    clear app.Interface;
                    app.Interface = [];

                    % 5. Wait before attempting the next connection
                    pause(2);  

                catch ME
                    disp(" Connection failed or interrupted：" + ME.message);
                    pause(3);  
                end
            end
        end

        function stopAcquisition(app)
            app.Running = false;
            disp("stop collecting");

            if ~isempty(app.Interface) && isvalid(app.Interface)
                try
                    writeline(app.Interface, "INITiate:CONTinuous OFF");
                catch
                end
                clear app.Interface;
                app.Interface = [];
            end
        end

        function readAndPlotData(app)

            try
                writeline(app.Interface, "DATA:POINTS?");
                pause(0.3);

                count = 0;
                if app.Interface.NumBytesAvailable > 0
                    countStr = readline(app.Interface);
                    count = str2double(strtrim(countStr));
                end

                if count > 0
                    writeline(app.Interface, "DATA:REMOVE? " + num2str(count));
                    pause(0.3);

                    while count > 0 && app.Interface.NumBytesAvailable > 0
                        line1 = strtrim(readline(app.Interface));

                        if isempty(line1) || ~contains(line1, ',')
                            disp(" line1 is invalid, skipping：" + line1);
                            count = count - 1;
                            continue;
                        end

                        fid = fopen('D:\GONG\data\laser_data.txt', 'a');
                        fprintf(fid, '%s\n', line1);
                        fclose(fid);

                        parts = split(line1, ',');

                        if numel(parts) >= 12
                            ux = str2double(parts(10));
                            uy = str2double(parts(11));
                            us = str2double(parts(12));

                            threshold = app.UsThresholdSlider.Value;
                            fprintf("us = %.5f, threshold = %.3f\n", us, threshold);

                            if us < threshold
                                if isprop(app, 'LaserStatusLabel') && isvalid(app.LaserStatusLabel)
                                    app.LaserStatusLabel.Text = "Status：no laser";
                                end
                                
                                app.Data(end+1, :) = table(NaN, NaN, us, ...
                                    'VariableNames', {'uxVoltage', 'uyVoltage', 'usVoltage'});

                                if height(app.Data) > 300
                                    app.Data = tail(app.Data, 300);
                                end

                                app.updateSpotOffset();

                                count = count - 1;
                                continue;
                            else
                                if isprop(app, 'LaserStatusLabel') && isvalid(app.LaserStatusLabel)
                                    app.LaserStatusLabel.Text = "Status: collecting laser information";
                                end
                            end

                            if all(~isnan([ux, uy, us]))
                                app.SampleIndex = app.SampleIndex + 1;
                                app.Data(end+1, :) = table(ux, uy, us, ...
                                    'VariableNames', {'uxVoltage', 'uyVoltage', 'usVoltage'});

                                if height(app.Data) > 300
                                    app.Data = tail(app.Data, 300);
                                end

                                app.updateScatterPlot(ux, uy, us)
                                % cla(app.UIAxes);
                                % hold(app.UIAxes, 'on');
                                % plot(app.UIAxes, [-0.5, 0.5], [0, 0], 'k-', 'LineWidth', 1.5);
                                % plot(app.UIAxes, [0, 0], [-0.5, 0.5], 'k-', 'LineWidth', 1.5);
                                % scatter(app.UIAxes, ux, uy, 60, us, 'filled');
                                % hold(app.UIAxes, 'off');
                                % app.XPositionEditField.Value = ux;
                                % app.YPositionEditField.Value = uy;
                                % xlim(app.UIAxes, [-0.5, 0.5]);
                                % ylim(app.UIAxes, [-0.5, 0.5]);
                                % colormap(app.UIAxes, turbo);
                                % colorbar(app.UIAxes);
                                % grid(app.UIAxes, 'on');
                                % title(app.UIAxes, 'Latest ux vs uy');
                                % xlabel(app.UIAxes, 'uxVoltage');
                                % ylabel(app.UIAxes, 'uyVoltage');

                                app.updateSpotOffset();
                                drawnow limitrate;
                            end
                        end
                        count = count - 1;
                    end
                    line2 = writeread(app.Interface, "SYSTem:DEBUg:MEMory?");
                    writelines(line2, 'memory_data.txt', WriteMode="append", LineEnding=" ");
                    disp("write memory data into：" + app.MemoryDataFile);
                end

            catch ME
                disp(" readAndPlotData wrong：" + ME.message);
            end
        end

        function updateScatterPlot(app, ux, uy, us)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % Draw cross axes
            plot(app.UIAxes, [-0.5, 0.5], [0, 0], 'k-', 'LineWidth', 1.5);
            plot(app.UIAxes, [0, 0], [-0.5, 0.5], 'k-', 'LineWidth', 1.5);

            % Draw scatter point
            scatter(app.UIAxes, ux, uy, 60, us, 'filled');
            hold(app.UIAxes, 'off');

            % Update axes
            xlim(app.UIAxes, [-0.5, 0.5]);
            ylim(app.UIAxes, [-0.5, 0.5]);
            colormap(app.UIAxes, turbo);
            colorbar(app.UIAxes);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Latest ux vs uy');
            xlabel(app.UIAxes, 'uxVoltage');
            ylabel(app.UIAxes, 'uyVoltage');

            % Update displayed values
            app.XPositionEditField.Value = ux;
            app.YPositionEditField.Value = uy;
        end

        function updateSpotOffset(app)
            N = 30;
            if height(app.Data) < 1
                return;
            end

            recentData = tail(app.Data, min(N, height(app.Data)));
            allX = recentData.uxVoltage;
            allY = recentData.uyVoltage;
            allZ = recentData.usVoltage;

            threshold = app.UsThresholdSlider.Value;
            validIdx = allZ >= threshold;
            allX = allX(validIdx);
            allY = allY(validIdx);
            allZ = allZ(validIdx);

            if isprop(app, 'HeatmapCountLabel') && isvalid(app.HeatmapCountLabel)
                app.HeatmapCountLabel.Text = "the points of heatmap：" + num2str(numel(allZ));
            end

            if isempty(allZ)
                cla(app.UIAxes2);
                title(app.UIAxes2, 'NO LASER BEAM');
                return;
            end

            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');

            x_range = linspace(min(allX), max(allX), 50);
            y_range = linspace(min(allY), max(allY), 50);
            [X, Y] = meshgrid(x_range, y_range);
            Z = griddata(allX, allY, allZ, X, Y, 'cubic');

            x0 = sum(allX .* allZ) / sum(allZ);
            y0 = sum(allY .* allZ) / sum(allZ);

            imagesc(app.UIAxes2, x_range, y_range, Z);
            axis(app.UIAxes2, 'tight');
            axis(app.UIAxes2, 'equal');
            set(app.UIAxes2, 'YDir', 'normal');
            colormap(app.UIAxes2, turbo);
            colorbar(app.UIAxes2);

            plot(app.UIAxes2, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            scatter(app.UIAxes2, allX, allY, 30, allZ, 'filled', 'MarkerEdgeColor', 'k');

            text(app.UIAxes2, x0 + 0.01, y0 + 0.01, 'central point', 'Color', 'r', 'FontSize', 10);
            text(app.UIAxes2, x0, y0 - 0.02, sprintf('ΔX = %.3f\nΔY = %.3f', x0, y0), ...
                'Color', 'r', 'FontSize', 10, 'FontWeight', 'bold');

            quiver(app.UIAxes2, 0, 0, x0, y0, 0, 'b', 'LineWidth', 2);
            plot(app.UIAxes2, 0, 0, 'wx', 'MarkerSize', 10, 'LineWidth', 2);

            xlabel(app.UIAxes2, 'X position');
            ylabel(app.UIAxes2, 'Y position');
            title(app.UIAxes2, 'Laser Spot Offset');
            app.HeatmapCountLabel.Text = "the points of the heatmap：" + num2str(numel(allZ));
            hold(app.UIAxes2, 'off');
        end

        function plotAnalysis(app)
            Status = app.data{:,2};
            Year   = app.data{:,3};
            Month  = app.data{:,4};
            Day    = app.data{:,5};
            Hour   = app.data{:,6};
            Minute = app.data{:,7};
            Second = app.data{:,8};
            VBus = app.data{:,9};
            uxVoltage = app.data{:,10};
            uyVoltage = app.data{:,11};
            usVoltage = app.data{:,12};

            % Generate datetime array from date and time columns
            x = datetime(Year, Month, Day, Hour, Minute, Second);

            %%  Figure 1: Status Plot
            plot(app.StatusAxes, x, Status, 'r-');
            xlabel(app.StatusAxes, 'Time');
            ylabel(app.StatusAxes, 'Status');
            title(app.StatusAxes, 'Status Over Time');
            ylim(app.StatusAxes, 'auto');
            yLimits = ylim(app.StatusAxes);
            yMin = floor(yLimits(1) / 10) * 10;
            yMax = ceil(yLimits(2) / 10) * 10;
            yticks(app.StatusAxes, yMin:10:yMax);% Set Y-ticks every 10 units
            grid(app.StatusAxes, 'on');

            %%  Figure 2: Voltages ux, uy, us
            plot(app.VoltageAxes, x, uxVoltage, 'b.', x, uyVoltage, 'g.', x, usVoltage, 'm.');
            legend(app.VoltageAxes, 'uxVoltage', 'uyVoltage', 'usVoltage');
            xlabel(app.VoltageAxes, 'Time');
            ylabel(app.VoltageAxes, 'Voltage (V)');
            grid(app.VoltageAxes, 'on');

            %%  Figure 3: VBus Voltage
            plot(app.VBusAxes, x, VBus, 'r.');
            xlabel(app.VBusAxes, 'Time');
            ylabel(app.VBusAxes, 'VBus (V)');
            title(app.VBusAxes, 'Bus Voltage');
            grid(app.VBusAxes, 'on');

            %%  Figures 4 & 5: Memory Data
            FreeHeap = app.memory_data(:,1);
            MinimumFreeHeap = app.memory_data(:,2);
            UnusedStackSize = app.memory_data(:,3);
            % i = 1:length(FreeHeap);
            numPoints = size(FreeHeap, 1); % Get the number of rows of data
            i = 1:numPoints; % Generate the index

            % Figure 4: Heap usage
            plot(app.FreeHeapAxes, i, FreeHeap, 'r.', i, MinimumFreeHeap, 'g.');
            legend(app.FreeHeapAxes, 'FreeHeap', 'MinimumFreeHeap');
            xlabel(app.FreeHeapAxes, 'Index');
            ylabel(app.FreeHeapAxes, 'Bytes');
            title(app.FreeHeapAxes, 'Heap Usage');
            grid(app.FreeHeapAxes, 'on');

            % Figure 5: Stack size usage
            plot(app.StackSizeAxes, i, UnusedStackSize, 'b.');
            legend(app.StackSizeAxes, 'UnusedStackSize');
            xlabel(app.StackSizeAxes, 'Index');
            ylabel(app.StackSizeAxes, 'Bytes');
            title(app.StackSizeAxes, 'Stack Size');
            grid(app.StackSizeAxes, 'on');

            %%  Figure 6: FFT Analysis 
            Fs = 1;         % Sampling frequency (Hz)
            T = 1/Fs;
            X = uxVoltage;
            X = X - mean(X); % remove the mean value
            L = length(X);
            t = (0:L-1) * T; % Time vector
            Y = fft(X); % Perform Fast Fourier Transform

            f = Fs/L*(0:L-1);
            plot(app.FFTAxes, f, abs(Y), 'LineWidth', 2);
            title(app.FFTAxes, "Complex Magnitude of FFT Spectrum");
            xlabel(app.FFTAxes, "f (Hz)");
            ylabel(app.FFTAxes, "|fft(X)|");
            grid(app.FFTAxes, "on");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.Data = table();
            app.SampleIndex = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Running = true;
            app.loopAcquisition();
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stopAcquisition(app);
        end

        % Value changed function: UsThresholdSlider
        function UsThresholdSliderValueChanged(app, event)
            newValue = app.UsThresholdSlider.Value;
            app.UsThresholdLabel.Text = "recent threshold：" + sprintf('%.3f', newValue);
        end

        % Button pushed function: LoadPlotDataButton
        function LoadPlotDataButtonPushed(app, event)
            %  [file, path] = uigetfile('*.txt', 'select TXT data'); 
            % if isequal(file, 0)
            %     uialert(app.UIFigure, 'user deselected', 'Tips', 'Icon', 'warning');
            %     return;
            % end
            % filename = fullfile(path, file); 
            % app.data = readtable(filename, 'Delimiter', ',','ReadVariableNames', false); 
            % 
            % 
            % uialert(app.UIFigure, 'File read successfully！', 'sucess', 'Icon', 'success');

            app.data = readtable(app.LaserDataFile);
            app.memory_data = readmatrix(app.MemoryDataFile);

            app.plotAnalysis();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [files, path] = uigetfile('*.txt', 'Please select laser data and memory data (select in order)', ...
                'MultiSelect', 'on');

            % User canceled the selection
            if isequal(files, 0)
                uialert(app.UIFigure, 'User cancelled the selection', 'Notice', 'Icon', 'warning');
                return;
            end

            % If only one file is selected, inform the user that two are required
            if ~iscell(files)
                files = {files};
            end

            if numel(files) < 2
                uialert(app.UIFigure, ...
                    'Please select two TXT files in order: first for laser data, second for memory data', ...
                    'Insufficient files', 'Icon', 'warning');
                return;
            end

            % Load laser data 
            filename1 = fullfile(path, files{1});
            try
                app.data = readtable(filename1, 'Delimiter', ',', 'ReadVariableNames', false);
                uialert(app.UIFigure, 'Laser data loaded successfully!', 'Success', 'Icon', 'success');
            catch
                uialert(app.UIFigure, 'Failed to load laser data', 'Error', 'Icon', 'error');
                return;
            end

            %  Load memory data 
            filename2 = fullfile(path, files{2});
            try
                app.memory_data = readmatrix(filename2, 'Delimiter', ' ');
                uialert(app.UIFigure, 'Memory data loaded successfully!', 'Success', 'Icon', 'success');
            catch
                uialert(app.UIFigure, 'Failed to load memory data', 'Error', 'Icon', 'error');
                return;
            end

            % Perform analysis and plot 
            try
                app.plotAnalysis();
            catch ME
                uialert(app.UIFigure, "Plotting failed: " + ME.message, 'Analysis Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RealtimeTab = uitab(app.TabGroup);
            app.RealtimeTab.Title = 'RealtimeTab';
            app.UIAxes = uiaxes(app.RealtimeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RealtimeTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.RealtimeTab, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.RealtimeTab, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.XPositionEditFieldLabel = uilabel(app.RealtimeTab);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.RealtimeTab, 'numeric');
            app.YPositionEditFieldLabel = uilabel(app.RealtimeTab);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.RealtimeTab, 'numeric');
            app.LaserStatusLabel = uilabel(app.RealtimeTab);
            app.LaserStatusLabel.Text = 'LaserStatusLabel';
            app.UsThresholdLabel = uilabel(app.RealtimeTab);
            app.UsThresholdLabel.Text = 'the einvironment threshold:';
            app.UsThresholdSliderLabel = uilabel(app.RealtimeTab);
            app.UsThresholdSliderLabel.Text = 'UsThresholdSlider';
            app.UsThresholdSlider = uislider(app.RealtimeTab);
            app.UsThresholdSlider.Limits = [0 0.2];
            app.UsThresholdSlider.MajorTicks = [0 0.05 0.1 0.15 0.2];
            app.UsThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UsThresholdSliderValueChanged, true);
            app.HeatmapCountLabel = uilabel(app.RealtimeTab);
            app.HeatmapCountLabel.Text = 'the points of heatmap';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'AnalysisTab';
            app.StatusOverTimePanel = uipanel(app.AnalysisTab);
            app.StatusOverTimePanel.Title = 'Status Over Time';
            app.StatusAxes = uiaxes(app.StatusOverTimePanel);
            title(app.StatusAxes, 'Title')
            xlabel(app.StatusAxes, 'X')
            ylabel(app.StatusAxes, 'Y')
            zlabel(app.StatusAxes, 'Z')
            app.SensorVoltageChannelsPanel = uipanel(app.AnalysisTab);
            app.SensorVoltageChannelsPanel.Title = 'Sensor Voltage Channels';
            app.VoltageAxes = uiaxes(app.SensorVoltageChannelsPanel);
            title(app.VoltageAxes, 'Title')
            xlabel(app.VoltageAxes, 'X')
            ylabel(app.VoltageAxes, 'Y')
            zlabel(app.VoltageAxes, 'Z')
            app.BusVoltagePanel = uipanel(app.AnalysisTab);
            app.BusVoltagePanel.Title = 'Bus Voltage';
            app.VBusAxes = uiaxes(app.BusVoltagePanel);
            title(app.VBusAxes, 'Title')
            xlabel(app.VBusAxes, 'X')
            ylabel(app.VBusAxes, 'Y')
            zlabel(app.VBusAxes, 'Z')
            app.ESP32MemoryUsagePanel = uipanel(app.AnalysisTab);
            app.ESP32MemoryUsagePanel.Title = 'ESP32 Memory Usage';
            app.StackSizeAxes = uiaxes(app.ESP32MemoryUsagePanel);
            title(app.StackSizeAxes, 'Title')
            xlabel(app.StackSizeAxes, 'X')
            ylabel(app.StackSizeAxes, 'Y')
            zlabel(app.StackSizeAxes, 'Z')
            app.FreeHeapAxes = uiaxes(app.ESP32MemoryUsagePanel);
            title(app.FreeHeapAxes, 'Title')
            xlabel(app.FreeHeapAxes, 'X')
            ylabel(app.FreeHeapAxes, 'Y')
            zlabel(app.FreeHeapAxes, 'Z')
            app.FFTofuxVoltagePanel = uipanel(app.AnalysisTab);
            app.FFTofuxVoltagePanel.Title = '	FFT of uxVoltage';
            app.FFTAxes = uiaxes(app.FFTofuxVoltagePanel);
            title(app.FFTAxes, 'Title')
            xlabel(app.FFTAxes, 'X')
            ylabel(app.FFTAxes, 'Y')
            zlabel(app.FFTAxes, 'Z')
            app.LoadPlotDataButton = uibutton(app.AnalysisTab, 'push');
            app.LoadPlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotDataButtonPushed, true);
            app.LoadPlotDataButton.Text = 'Load & Plot Data';
            app.Button = uibutton(app.AnalysisTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timer_4_2_realtimeok

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end