classdef viel_dioden_scatter_timer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        updatethepointButton  matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        stopButton            matlab.ui.control.Button
        startButton           matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        interface     % TCP server
        TimerObj      % timer
        data          % save ux,uy,us
        dataLogFile = "esp32_test_data.txt";
        memoryLogFile = "esp32_memory_data.txt";
        sampleIndex = 0 
        % collecting = false
    end

    methods (Access = private)

        % Initialization function
        function startupFcn(app)
            app.data = table([], [], [], 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
            writelines("", app.dataLogFile);
            writelines("", app.memoryLogFile);
        end

        function startAcquisition(app)
            host = "10.203.196.138";
            port = 5025;
            try
                app.interface = tcpclient(host, port, "Timeout", 10);
                configureTerminator(app.interface, "LF");
                flush(app.interface);
                disp("Connected TCP");
                % app.collecting = true;
                % Continuous data collection mode activated
                writeline(app.interface, "INITiate:CONTinuous:ALL ON");
                % sampleIndex = 0;

                %Creating and starting a timer
                app.TimerObj = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.3, ...
                    'TimerFcn', @(~,~)app.readAndUpdate());

                start(app.TimerObj);

            catch ME
                uialert(app.UIFigure, "connection failed：" + ME.message, "Wrong");
                disp(getReport(ME)); % print the error message
            end
        end

        % Get real time data
        function readAndUpdate(app)          
            
            if isempty(app.interface) || ~isvalid(app.interface)
                disp("stop reading");
                return;
            end

            try
                % Ask for the number of readable data
                writeline(app.interface, "DATA:POINts?");
                pause(0.3);

                if app.interface.NumBytesAvailable > 0
                    countStr = readline(app.interface);
                    count = str2double(strtrim(countStr));
                    disp("points: " + count);
                else
                    disp("no return points");
                    count = 0;
                end

                if count > 0
                    line1 = writeread(app.interface, "DATA:REMove?" + num2str(count)); %+ num2str(count)
                    pause(0.3);

                    while (count > 0)
                        if strlength(line1) > 0
                            writelines(string(line1), app.dataLogFile, WriteMode="append", LineEnding=" ");
                            parts = split(strtrim(line1), ',');

                            if numel(parts) >= 12
                                ux = str2double(parts(10));
                                uy = str2double(parts(11));
                                us = str2double(parts(12));

                                if all(~isnan([ux, uy, us]))
                                    % app.sampleIndex = app.sampleIndex + 1;
                                    newRow = table(ux, uy, us, 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
                                    % app.data(end+1, :) = newRow;

                                    if isempty(app.data)
                                        app.data = newRow;
                                    else                                        
                                        app.data(end+1, :) = newRow;
                                    end
                                    % app.data = newRow;
                                    % app.data = [app.data; newRow];
                                    % app.data(end+1, :) = table(ux, uy, us); %table(ux, uy, us);
                                    % app.data(end+1, :) = {ux, uy, us};
                                     
                                     if height(app.data) > 200
                                        app.data = tail(app.data, 200);
                                    
                                    end
                                   
                                    disp(app.data(end, :));
                                    disp("current line: " + height(app.data));

                                    app.updateStaticPlot();
                                    drawnow limitrate;
                                    end
                                end
                        end
                        count = count - 1;
                        if count > 0
                            line1 = readline(app.interface);
                        end
                    end
                end

                line2 = writeread(app.interface, "SYSTem:DEBUg:MEMory?");
                writelines(line2, app.memoryLogFile, WriteMode="append", LineEnding=" ");

                % Limit data points to a maximum of 200
                % if height(app.data) > 200
                %     app.data = tail(app.data, 200);
                % end
                % 
                % app.updateStaticPlot();
            catch ME
                disp("Data read errors：" + ME.message);
            end
        end


            % update the picture
            function updateStaticPlot(app)
                if height(app.data) < 1
                    return;
                end
                lastRow = app.data(end, :);
                uxVoltage = lastRow.uxVoltage;
                uyVoltage = lastRow.uyVoltage;
                app.XEditField.Value = uxVoltage;
                app.YEditField.Value = uyVoltage;

                range = max(abs([uxVoltage, uyVoltage]));
                minRange = 0.01;  
                axisLimit = max(range, minRange);

                x_min = -axisLimit;
                x_max = axisLimit;
                y_min = -axisLimit;
                y_max = axisLimit;

                xlim(app.UIAxes, [x_min, x_max]);
                ylim(app.UIAxes, [y_min, y_max]);
                xticks(app.UIAxes, x_min:axisLimit/2:x_max);
                yticks(app.UIAxes, y_min:axisLimit/2:y_max);
               
                % % Set colour mapping range
                %  c_min = min(Intensity);
                %  c_max = max(Intensity);
                %  if c_min == c_max
                %      c_min = c_min - 0.001;
                %      c_max = c_max + 0.001;
                %  end
                %  clim(app.UIAxes, [c_min, c_max]);

                % Ensure symmetry of X & Y axes (centre alignment)
                % x_min = min(uxVoltage);
                % x_max = max(uxVoltage);
                % y_min = min(uyVoltage);
                % y_max = max(uyVoltage);
                % 
                % axis_limit = max(abs([x_min, x_max, y_min, y_max]));
                % if axis_limit == 0
                %     axis_limit = 1;  % 安全默认值
                % end
                % 
                % x_min = -axis_limit;
                % x_max = axis_limit;
                % y_min = -axis_limit;
                % y_max = axis_limit;
                % 
                % if x_min == x_max
                %     x_min = x_min - 0.001;
                %     x_max = x_max + 0.001;
                % end
                % if y_min == y_max
                %     y_min = y_min - 0.001;
                %     y_max = y_max + 0.001;
                % end
                % 
                % x_margin = 0.02; % extra margin
                % y_margin = 0.02;
                % 
                % xlim(app.UIAxes, [x_min - x_margin, x_max + x_margin]);
                % ylim(app.UIAxes, [y_min - y_margin, y_max + y_margin]);
                % 
                % % Set X/Y axis scale (0.05 interval)
                % x_step = 0.05;
                % y_step = 0.05;
                % xticks(app.UIAxes, x_min:x_step:x_max);
                % yticks(app.UIAxes, y_min:y_step:y_max);

                % Clear the image to prevent overlapping
                cla(app.UIAxes);
                hold(app.UIAxes, "on");

                % Draw X-axis & Y-axis centre lines
                plot(app.UIAxes, [x_min, x_max], [0, 0], 'k-', 'LineWidth', 1.5); % X axis
                plot(app.UIAxes, [0, 0], [y_min, y_max], 'k-', 'LineWidth', 1.5); % Y axis

                % Plot scatter with colours representing light intensity
                scatter(app.UIAxes, uxVoltage, uyVoltage, 'filled');% The point size range is set between 5 and 45.
                % % text(app.UIAxes, uxVoltage + 0.01*axisLimit, uyVoltage + 0.01*axisLimit, ...
                %     sprintf('X = %.4f\nY = %.4f', uxVoltage, uyVoltage), ...
                %     'FontSize', 10, 'Color', 'b', 'FontWeight', 'bold');

                % % add the colour bar representing light intensity
                % colormap(app.UIAxes, jet); % use jet colour mapping and make the colour from blue to red
                % colorbar(app.UIAxes); % Show colour bar
                % clim(app.UIAxes, [c_min, c_max]); % set the colour range

                xlabel(app.UIAxes, 'uxVoltage (V)');
                ylabel(app.UIAxes, 'uyVoltage (V)');
                title(app.UIAxes, 'XY Scatter Plot with Intensity');

                hold(app.UIAxes, "off");


                % Heatmap
                allX = app.data.uxVoltage;
                allY = app.data.uyVoltage;
                allZ = app.data.usVoltage;

                % % mean
                % T = table(allX, allY, allZ, 'VariableNames', {'X', 'Y', 'Z'});
                % T_unique = groupsummary(T, {'X', 'Y'}, 'mean', 'Z');
                % allX = T_unique.X;
                % allY = T_unique.Y;
                % allZ = T_unique.mean_Z;
                % 
                % if numel(allX) < 4 || range(allX) == 0 || range(allY) == 0
                %     disp("because of less points, then skip ");
                %     cla(app.UIAxes2);
                %     % title(app.UIAxes2, 'less data）');
                %     return;
                % end

                
                cla(app.UIAxes2);
                hold(app.UIAxes2, 'on');

                % create grid
                x_range = linspace(min(allX), max(allX), 50);
                y_range = linspace(min(allY), max(allY), 50);
                [X, Y] = meshgrid(x_range, y_range);
                Z = griddata(allX, allY, allZ, X, Y, 'cubic');

                % center points
                targetX = 0;
                targetY = 0;
                x0 = mean(allX);
                y0 = mean(allY);
                deltaX = x0 - targetX;
                deltaY = y0 - targetY;

                % heatmap
                imagesc(app.UIAxes2, x_range, y_range, Z);
                set(app.UIAxes2, 'YDir', 'normal');
                colormap(app.UIAxes2, turbo);
                colorbar(app.UIAxes2);

                % add the origin line
                plot(app.UIAxes2, [min(x_range), max(x_range)], [0, 0], 'k--');
                plot(app.UIAxes2, [0, 0], [min(y_range), max(y_range)], 'k--');

                % show the center points
                plot(app.UIAxes2, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
                text(app.UIAxes2, x0 + 0.01, y0 + 0.01, 'central point', 'Color', 'r', 'FontSize', 10);

                
                text(app.UIAxes2, x0, y0 - 0.02, ...
                    sprintf('ΔX = %.3f\nΔY = %.3f', deltaX, deltaY), ...
                    'Color', 'w', 'FontSize', 10, 'FontWeight', 'bold');

             
                xlabel(app.UIAxes2, 'X position');
                ylabel(app.UIAxes2, 'Y position');
                title(app.UIAxes2, 'Laser Spot Offset');
                hold(app.UIAxes2, 'off');
            end

                % % Generate 50 equally spaced points in X/Y direction
                % x_range = linspace(min(X), max(X), 50);
                % y_range = linspace(min(Y), max(Y), 50);
                % [X_grid, Y_grid] = meshgrid(x_range, y_range);% Generate Grid Point Matrix
            % 
            %     % Calculate intensity values on the grid by interpolation
            %     Intensity_grid = griddata(X, Y, Intensity, X_grid, Y_grid, 'cubic');
            % 
            %     % draw the Heatmap
            %     imagesc(app.UIAxes2, x_range, y_range, Intensity_grid);
            %     set(app.UIAxes2, 'YDir', 'normal');  % Invert Y-axis
            %     colormap(app.UIAxes2,turbo);  % Set the colour mapping
            %     colorbar(app.UIAxes2);  % Add a colour bar
            % 
            %     xlabel(app.UIAxes2, 'X position');
            %     ylabel(app.UIAxes2, 'Y position');
            %     title(app.UIAxes2, 'Laser Spot Intensity Heatmap');
            % end

            % Stop Collection
            function stopAcquisition(app)
                % app.collecting = false;
                %
                % if ~isempty(app.interface)
                %     writeline(app.interface, "INITiate:CONTinuous OFF");
                %     clear app.interface;
                %     app.interface = [];
                % end
                %
                % disp("Stop Collection");
                try
                    if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                        stop(app.TimerObj);
                        delete(app.TimerObj);
                    end

                    if ~isempty(app.interface)
                        writeline(app.interface, "INITiate:CONTinuous OFF");
                        clear app.interface;
                    end

                    disp("Stop Collection");
                catch ME
                    disp("Stop failed: " + ME.message);
                end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.stopAcquisition();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.startAcquisition();
        end

        % Button pushed function: updatethepointButton
        function updatethepointButtonPushed(app, event)
            app.readAndUpdate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'laser spot position with intensity')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Heatmap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'4 quadrat dioden scatterplot'};
            app.updatethepointButton = uibutton(app.UIFigure, 'push');
            app.updatethepointButton.ButtonPushedFcn = createCallbackFcn(app, @updatethepointButtonPushed, true);
            app.updatethepointButton.Text = 'update the point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viel_dioden_scatter_timer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end