classdef viel_dioden_scatter_timer2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        updatethepointButton  matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        stopButton            matlab.ui.control.Button
        startButton           matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        interface     % TCP server
        TimerObj      % timer
        data          % save ux,uy,us
        dataLogFile = "esp32_test_data.txt";
        memoryLogFile = "esp32_memory_data.txt";
        % sampleIndex = 0 
        % collecting = false
    end

    methods (Access = private)

        % Initialization function
        function startupFcn(app)
            app.data = table([], [], [], 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
            writelines("", app.dataLogFile);
            writelines("", app.memoryLogFile);
        end

        function startAcquisition(app)
            host = "10.203.196.138";
            port = 5025;
            try
                app.interface = tcpclient(host, port, "Timeout", 10);
                configureTerminator(app.interface, "LF");
                flush(app.interface);

                disp("Connected TCP");

                writeline(app.interface, "DATA:REMove?9999");
                writeline(app.interface, "INITiate:CONTinuous:ALL ON");
               

                %Creating and starting a timer
                app.TimerObj = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.5, ...
                    'TimerFcn', @(~,~)app.readAndUpdate());

                start(app.TimerObj);

            catch ME
                uialert(app.UIFigure, "connection failed：" + ME.message, "Wrong");
                disp(getReport(ME)); 
            end
        end


        % Get real time data
        function readAndUpdate(app)          
            
            if isempty(app.interface) || ~isvalid(app.interface)
                disp("stop reading");
                return;
            end

            try
                % Ask for the number of readable data
                writeline(app.interface, "DATA:POINts?");
                pause(0.1);

                if app.interface.NumBytesAvailable > 0
                    countStr = readline(app.interface);
                    count = str2double(strtrim(countStr));
                    disp("points: " + count);
                else
                    disp("no return points");
                    count = 0;
                end

                if count > 0
                    % Limit to max 50 per read
                    maxToRead = 50;
                    countToRead = min(count, maxToRead);
                    line1 = writeread(app.interface, "DATA:REMove?" +  num2str(countToRead)); %+ num2str(count)
                    disp(line1);
                    pause(0.1);

                    lines = split(line1, '\n');

                    for i = 1:length(lines)
                        currentLine = strtrim(lines{i});
                        if strlength(currentLine) > 0
                            writelines(string(currentLine), app.dataLogFile, WriteMode="append", LineEnding=" ");
                            parts = split(currentLine, ',');
                            % while (count > 0)
                            %     if strlength(line1) > 0
                            %         writelines(string(line1), app.dataLogFile, WriteMode="append", LineEnding=" ");
                            %         parts = split(strtrim(line1), ',');

                            if numel(parts) >= 12
                                ux = str2double(parts(10));
                                uy = str2double(parts(11));
                                us = str2double(parts(12));

                                if all(~isnan([ux, uy, us]))
                                    % app.sampleIndex = app.sampleIndex + 1;
                                    newRow = table(ux, uy, us, 'VariableNames', {'uxVoltage','uyVoltage','usVoltage'});
                                   

                                    if isempty(app.data)
                                        app.data = newRow;
                                    else                                        
                                        app.data(end+1, :) = newRow;
                                    end
                                   
                                     
                                     if height(app.data) > 200
                                        app.data = tail(app.data, 200);
                                    
                                    end
                                   
                                    disp(app.data(end, :));
                                    disp("current line: " + height(app.data));

                                    app.updateStaticPlot();
                                    drawnow limitrate;
                                    end
                                end
                        end
                        % count = count - 1;
                        % if count > 0
                        %     line1 = readline(app.interface);
                        % end
                    end
                end

                line2 = writeread(app.interface, "SYSTem:DEBUg:MEMory?");
                writelines(line2, app.memoryLogFile, WriteMode="append", LineEnding=" ");

              
            catch ME
                disp("Data read errors：" + ME.message);
            end
        end


            %update the picture
            function updateStaticPlot(app)
                if height(app.data) < 1
                    return;
                end
                
                cla(app.UIAxes);
                hold(app.UIAxes, "on");

                lastRow = app.data(end, :);
                uxVoltage = lastRow.uxVoltage;
                uyVoltage = lastRow.uyVoltage;
                app.XEditField.Value = uxVoltage;
                app.YEditField.Value = uyVoltage;

                range = max(abs([uxVoltage, uyVoltage]));
                minRange = 0.01;  
                axisLimit = max(range, minRange);

                x_min = -axisLimit;
                x_max = axisLimit;
                y_min = -axisLimit;
                y_max = axisLimit;

                xlim(app.UIAxes, [x_min, x_max]);
                ylim(app.UIAxes, [y_min, y_max]);
                xticks(app.UIAxes, x_min:axisLimit/2:x_max);
                yticks(app.UIAxes, y_min:axisLimit/2:y_max);
               
                % Draw X-axis & Y-axis centre lines
                plot(app.UIAxes, [x_min, x_max], [0, 0], 'k-', 'LineWidth', 1.5); % X axis
                plot(app.UIAxes, [0, 0], [y_min, y_max], 'k-', 'LineWidth', 1.5); % Y axis

                % Plot scatter with colours representing light intensity
                scatter(app.UIAxes, uxVoltage, uyVoltage, 60,'filled');
               
                xlabel(app.UIAxes, 'uxVoltage (V)');
                ylabel(app.UIAxes, 'uyVoltage (V)');
                title(app.UIAxes, 'XY Scatter Plot with Intensity');

                hold(app.UIAxes, "off");


                % plot Laser Spot Offset
                allX = app.data.uxVoltage;
                allY = app.data.uyVoltage;
                allZ = app.data.usVoltage;

                % Check for invalid or zero intensity data
                if sum(allZ) == 0
                    disp("Total Z intensity is zero. Cannot compute center.");
                    return;
                end

                if any(isnan(allZ))
                    disp("Z contains invalid NaN values. Skipping plot.");
                    return;
                end

                % Clear previous plot and prepare axes
                cla(app.UIAxes2);
                hold(app.UIAxes2, 'on');

                % Generate interpolation grid for X and Y
                x_range = linspace(min(allX), max(allX), 50);
                y_range = linspace(min(allY), max(allY), 50);
                [X, Y] = meshgrid(x_range, y_range);

                % Interpolate Z values (intensity) on the grid using cubic interpolation
                Z = griddata(allX, allY, allZ, X, Y, 'cubic');

                % Set axis limits to match data range
                xlim(app.UIAxes2, [min(allX), max(allX)]);
                ylim(app.UIAxes2, [min(allY), max(allY)]);


                % Compute intensity-weighted centroid of the laser spot
                targetX = 0;% expected center
                targetY = 0;
                x0 = sum(allX .* allZ) / sum(allZ);
                y0 = sum(allY .* allZ) / sum(allZ);
                deltaX = x0 - targetX;
                deltaY = y0 - targetY;

                % Plot heatmap of laser spot intensity
                imagesc(app.UIAxes2, x_range, y_range, Z);
                axis(app.UIAxes2, 'tight');
                axis(app.UIAxes2, 'equal');
                set(app.UIAxes2, 'YDir', 'normal');
                colormap(app.UIAxes2, turbo);
                colorbar(app.UIAxes2);

                % Draw dashed lines for origin axes
                plot(app.UIAxes2, [min(x_range), max(x_range)], [0, 0], 'k--');
                plot(app.UIAxes2, [0, 0], [min(y_range), max(y_range)], 'k--');

                % Plot center point (centroid) in red
                plot(app.UIAxes2, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2);

                % Overlay original measurement points with color-coded intensity
                scatter(app.UIAxes2, allX, allY, 30, allZ, 'filled', 'MarkerEdgeColor', 'k');

                % Label the center point
                text(app.UIAxes2, x0 + 0.01, y0 + 0.01, 'central point', 'Color', 'r', 'FontSize', 10);

                % Display delta offset values near center point
                text(app.UIAxes2, x0, y0 - 0.02, ...
                    sprintf('ΔX = %.3f\nΔY = %.3f', deltaX, deltaY), ...
                    'Color', 'w', 'FontSize', 10, 'FontWeight', 'bold');

                % Draw offset arrow from (0,0) to the actual center
                quiver(app.UIAxes2,0, 0, x0, y0, 0, 'w', 'LineWidth', 2, 'MaxHeadSize', 0.5);

                % Mark the expected center (target) at the origin
                plot(0, 0, 'wx', 'MarkerSize', 10, 'LineWidth', 2);

                xlabel(app.UIAxes2, 'X position');
                ylabel(app.UIAxes2, 'Y position');
                title(app.UIAxes2, 'Laser Spot Offset');
                cb = colorbar(app.UIAxes2);
                ylabel(cb, 'us Intensity (V)');
                hold(app.UIAxes2, 'off');
            end

            % Stop Collection
            function stopAcquisition(app)
                % app.collecting = false;
                %
                % if ~isempty(app.interface)
                %     writeline(app.interface, "INITiate:CONTinuous OFF");
                %     clear app.interface;
                %     app.interface = [];
                % end
                %
                % disp("Stop Collection");
                try
                    if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                        stop(app.TimerObj);
                        delete(app.TimerObj);
                    end

                    if ~isempty(app.interface)
                        writeline(app.interface, "INITiate:CONTinuous OFF");
                        clear app.interface;
                    end

                    disp("Stop Collection");
                catch ME
                    disp("Fail Stop: " + ME.message);
                end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            app.stopAcquisition();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.startAcquisition();
        end

        % Button pushed function: updatethepointButton
        function updatethepointButtonPushed(app, event)
            app.readAndUpdate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'laser spot position with intensity')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Heatmap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'4 quadrat dioden scatterplot'};
            app.updatethepointButton = uibutton(app.UIFigure, 'push');
            app.updatethepointButton.ButtonPushedFcn = createCallbackFcn(app, @updatethepointButtonPushed, true);
            app.updatethepointButton.Text = 'update the point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viel_dioden_scatter_timer2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end